classdef Exoskeleton_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExoskeletonLabel                matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        UserTab                         matlab.ui.container.Tab
        UserinformationPanel            matlab.ui.container.Panel
        MassLabel                       matlab.ui.control.Label
        AgeLabel                        matlab.ui.control.Label
        SexLabel                        matlab.ui.control.Label
        NameLabel                       matlab.ui.control.Label
        Mass                            matlab.ui.control.NumericEditField
        kgLabel                         matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        EditField_3                     matlab.ui.control.EditField
        PraticeInfomationPanel          matlab.ui.container.Panel
        TotalTimeEditFieldLabel         matlab.ui.control.Label
        TotalTimeEditField              matlab.ui.control.NumericEditField
        TotalstepEditFieldLabel         matlab.ui.control.Label
        TotalstepEditField              matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        MovingModePanel                 matlab.ui.container.Panel
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        Mode1Button                     matlab.ui.control.RadioButton
        Mode2Button                     matlab.ui.control.RadioButton
        Mode3Button                     matlab.ui.control.RadioButton
        SpeedButtonGroup                matlab.ui.container.ButtonGroup
        LowButton                       matlab.ui.control.RadioButton
        MediumButton                    matlab.ui.control.RadioButton
        FastButton                      matlab.ui.control.RadioButton
        SelectButton                    matlab.ui.control.Button
        SelectedMode                    matlab.ui.control.EditField
        StepEditFieldLabel              matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        ContinuousMoveButton            matlab.ui.control.StateButton
        CurrentPanel                    matlab.ui.container.Panel
        LampDC4                         matlab.ui.control.Lamp
        LampDC3                         matlab.ui.control.Lamp
        LampDC2                         matlab.ui.control.Lamp
        LampDC1                         matlab.ui.control.Lamp
        LeftUpLabel                     matlab.ui.control.Label
        CurrentGauge1                   matlab.ui.control.LinearGauge
        LeftDownLabel                   matlab.ui.control.Label
        CurrentGauge2                   matlab.ui.control.LinearGauge
        RightUpLabel                    matlab.ui.control.Label
        CurrentGauge3                   matlab.ui.control.LinearGauge
        RightDownLabel                  matlab.ui.control.Label
        CurrentGauge4                   matlab.ui.control.LinearGauge
        MaxC3                           matlab.ui.control.NumericEditField
        MinC3                           matlab.ui.control.NumericEditField
        MaxC4                           matlab.ui.control.NumericEditField
        MinC4                           matlab.ui.control.NumericEditField
        MaxC1                           matlab.ui.control.NumericEditField
        MinC1                           matlab.ui.control.NumericEditField
        MaxC2                           matlab.ui.control.NumericEditField
        MinC2                           matlab.ui.control.NumericEditField
        LeftGaugeLabel                  matlab.ui.control.Label
        LeftGauge                       matlab.ui.control.LinearGauge
        RightGaugeLabel                 matlab.ui.control.Label
        RightGauge                      matlab.ui.control.LinearGauge
        LampLeft                        matlab.ui.control.Lamp
        LampRight                       matlab.ui.control.Lamp
        TechnicianTab                   matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        PIDConfigurationPanel           matlab.ui.container.Panel
        KpEditField_2                   matlab.ui.control.NumericEditField
        KiEditField_2                   matlab.ui.control.NumericEditField
        KdEditField_2                   matlab.ui.control.NumericEditField
        HipLeftLabel                    matlab.ui.control.Label
        KneeLeftLabel                   matlab.ui.control.Label
        KpEditField_2Label              matlab.ui.control.Label
        KpEditField_1                   matlab.ui.control.NumericEditField
        KiEditField_2Label              matlab.ui.control.Label
        KiEditField_1                   matlab.ui.control.NumericEditField
        KdEditField_2Label              matlab.ui.control.Label
        KdEditField_1                   matlab.ui.control.NumericEditField
        KpEditField_4                   matlab.ui.control.NumericEditField
        KiEditField_4                   matlab.ui.control.NumericEditField
        KdEditField_4                   matlab.ui.control.NumericEditField
        HipRightLabel                   matlab.ui.control.Label
        KneeRightLabel                  matlab.ui.control.Label
        KpEditField_3                   matlab.ui.control.NumericEditField
        KiEditField_3                   matlab.ui.control.NumericEditField
        KdEditField_3                   matlab.ui.control.NumericEditField
        SetPIDButton                    matlab.ui.control.Button
        GoHomeButton                    matlab.ui.control.Button
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ClearPlotButton                 matlab.ui.control.Button
        ErrorTheta1Label                matlab.ui.control.Label
        ErrorTheta1                     matlab.ui.control.NumericEditField
        ErrorTheta2Label                matlab.ui.control.Label
        ErrorTheta2                     matlab.ui.control.NumericEditField
        ErrorTheta3Label                matlab.ui.control.Label
        ErrorTheta3                     matlab.ui.control.NumericEditField
        ErrorTheta4Label                matlab.ui.control.Label
        ErrorTheta4                     matlab.ui.control.NumericEditField
        DebugButton                     matlab.ui.control.Button
        ResetMotorButton                matlab.ui.control.Button
        HomeTab                         matlab.ui.container.Tab
        ActualPositionPanel             matlab.ui.container.Panel
        Theta3EditFieldLabel            matlab.ui.control.Label
        Theta3EditField                 matlab.ui.control.NumericEditField
        Theta1EditFieldLabel            matlab.ui.control.Label
        Theta1EditField                 matlab.ui.control.NumericEditField
        Theta4EditFieldLabel            matlab.ui.control.Label
        Theta4EditField                 matlab.ui.control.NumericEditField
        Theta2EditFieldLabel            matlab.ui.control.Label
        Theta2EditField                 matlab.ui.control.NumericEditField
        HomePositionPanel               matlab.ui.container.Panel
        Theta3EditField_2Label          matlab.ui.control.Label
        Theta3EditField_Home            matlab.ui.control.NumericEditField
        Theta1EditField_2Label          matlab.ui.control.Label
        Theta1EditField_Home            matlab.ui.control.NumericEditField
        Theta4EditField_2Label          matlab.ui.control.Label
        Theta4EditField_Home            matlab.ui.control.NumericEditField
        Theta2EditField_2Label          matlab.ui.control.Label
        Theta2EditField_Home            matlab.ui.control.NumericEditField
        SetHomeButton                   matlab.ui.control.Button
        TestRespondPanel                matlab.ui.container.Panel
        HipRightButton                  matlab.ui.control.Button
        HipLeftButton                   matlab.ui.control.Button
        KneeLeftButton                  matlab.ui.control.Button
        KneeRightButton                 matlab.ui.control.Button
        HandlesPanel                    matlab.ui.container.Panel
        SelecthandlesstepDropDownLabel  matlab.ui.control.Label
        SelecthandlesstepDropDown       matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        AddHLButton                     matlab.ui.control.Button
        SubHLButton                     matlab.ui.control.Button
        HipLeftLabel_2                  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        AddKLButton                     matlab.ui.control.Button
        SubKLButton                     matlab.ui.control.Button
        KneeLeftLabel_2                 matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        AddHRButton                     matlab.ui.control.Button
        SubHRButton                     matlab.ui.control.Button
        HipRightLabel_2                 matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        AddKRButton                     matlab.ui.control.Button
        SubKRButton                     matlab.ui.control.Button
        KneeRightLabel_2                matlab.ui.control.Label
        GetPositionButton               matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
    end


    properties (Access = private)
        TSample=0.3;
        TimerNTN=timer('ExecutionMode','fixedRate');
        TotalTime;
        CurrentGauge;UIAxes;Lamp;ThetaEditField;
        %
        Hip_Left_Motor;
        Knee_Left_Motor;
        Hip_Right_Motor;
        Knee_Right_Motor;
        Motor;
        i=1; N;
        %
        tValue;
        Error;ThetaRef;
        ThetaValue;CurrentValue;
        %Intial Condition
        qcTheta0;
        Coef_Motor = 4096/360*100;   %Pluse/rev*ratio
        MaxVelocity = 1000;       %RPM
        MaxAcceleration = 1250;
        Kp;Ki;Kd;
        MaxHip=40*4096/360*100; MinHip=-10*4096/360*100;
        MaxKnee=70*4096/360*100; MinKnee=-5*4096/360*100;
    end

    methods (Access = private)
        
        function Show_Countdown(app)
            app.TotalTime=app.TotalTime+app.TSample;
            app.TotalTimeEditField.Value=app.TotalTime;
        end
        
        function PlotExo(app)
            for i_plot=1:4
                plot(app.UIAxes(i_plot),[app.tValue(app.i-1) app.tValue(app.i)],[app.ThetaValue(app.i-1,i_plot) app.ThetaValue(app.i,i_plot)],'r','LineWidth',1)
                hold(app.UIAxes(i_plot),'on');
            end
        end
    
        function CheckT(app)
            for i_checkT=1:4
                app.CurrentValue(app.i,i_checkT)=app.Motor(i_checkT).ActualCurrent/1000;
                if app.CurrentValue(app.i,i_checkT) > 10
                    app.StopButtonPushed;
                end
                app.CurrentGauge(i_checkT).Value=double(app.CurrentValue(app.i,i_checkT));
            end
        end
        
        function CheckStrength(app)
            strength_left=(20-abs(app.ThetaValue(app.i,1) + app.ThetaValue(app.i,2)))*5;
            strength_right=(20-abs(app.ThetaValue(app.i,3) + app.ThetaValue(app.i,4)))*5;
            
            if (strength_left > 0) && (strength_right > 0)
                app.LeftGauge.Value=strength_left;
                app.RightGauge.Value=strength_right;
                %%%%%%%%%%
%                 if strength_left < 30
%                     app.LampLeft.Color='green';
%                 else
%                    if strength_left < 70
%                        app.LampLeft.Color='orange';
%                    else
%                        app.LampLeft.Color='red';
%                    end
%                 end
%                 %%%%%%%%%%
%                 if strength_right < 30
%                     app.LampLeft.Color='green';
%                 else
%                    if strength_right < 70
%                        app.LampLeft.Color='orange';
%                    else
%                        app.LampLeft.Color='red';
%                    end
%                 end
                %%%%%%%%%%
            end
            
        end
        
        function Execute(app)
            for i_exe=1:4
                app.ThetaValue(app.i,i_exe)=(app.Motor(i_exe).ActualPosition-app.qcTheta0(i_exe))/app.Coef_Motor;
                
                app.Error(app.i,i_exe)=app.ThetaRef(app.i,i_exe)-app.ThetaValue(app.i,i_exe);
                if app.Error(app.i,i_exe) > 10
                    app.StopButtonPushed;
                end
                VelocityIn=app.Kp(i_exe)*app.Error(app.i,i_exe)+app.Kd(i_exe)*(app.Error(app.i,i_exe)-app.Error(app.i-1,i_exe))+app.Ki(i_exe)*(app.Error(app.i,i_exe)+app.Error(app.i-1,i_exe));
                if VelocityIn > app.MaxVelocity
                    VelocityIn=app.MaxVelocity;
                elseif VelocityIn < -app.MaxVelocity
                    VelocityIn=-app.MaxVelocity;
                end
                debug_VelocityIn=VelocityIn
                app.Motor(i_exe).MotionInVelocity(VelocityIn,app.MaxAcceleration);
            end
            
        end
        
        function NguyenThanhNguyen(app)
            
            if((app.Motor(1).IsInErrorState==1)||(app.Motor(2).IsInErrorState==1)||(app.Motor(3).IsInErrorState==1)||(app.Motor(4).IsInErrorState==1))
                app.StopButtonPushed;
            else
                app.i=app.i+1;
                debug_i=app.i
                debug_N=app.N
                if app.i > app.N
                    check=app.ContinuousMoveButton.Value;
                    if check==0 
                        app.StopButtonPushed;
                    else
                        app.i=1;
                        app.ClearPlotButtonPushed;
                    end
                else
                    %app.Show_Countdown;
                    app.Execute;                
                    app.CheckT;
                    app.PlotExo;
                    app.CheckStrength;
                end
            end
        end
        
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Hip_Left_Motor = Epos4(3,0);
            app.Knee_Left_Motor = Epos4(4,0);
            app.Hip_Right_Motor = Epos4(1,0);
            app.Knee_Right_Motor = Epos4(2,0);
            % Set up Timer
            app.TimerNTN.TimerFcn = @(~, ~)NguyenThanhNguyen(app);
            app.TotalTime=0;
            % Set up Motor
            app.Motor=[app.Hip_Left_Motor app.Knee_Left_Motor app.Hip_Right_Motor app.Knee_Right_Motor];

            % Set up Display Matrix
            app.CurrentGauge=[app.CurrentGauge1 app.CurrentGauge2 app.CurrentGauge3 app.CurrentGauge4];
            app.UIAxes=[app.UIAxes1 app.UIAxes2 app.UIAxes3 app.UIAxes4];
            app.Lamp=[app.LampDC1 app.LampDC2 app.LampDC3 app.LampDC4];
            % Read Home
            KHome=csvread('Home.csv');
            app.qcTheta0=[KHome(1) KHome(2) KHome(3) KHome(4)];
            app.Theta1EditField_Home.Value=app.qcTheta0(1)/app.Coef_Motor;
            app.Theta2EditField_Home.Value=app.qcTheta0(2)/app.Coef_Motor;
            app.Theta3EditField_Home.Value=app.qcTheta0(3)/app.Coef_Motor;
            app.Theta4EditField_Home.Value=app.qcTheta0(4)/app.Coef_Motor;
            % Read PID

            load_system('tf4M_CSVposRef_ctrlDesign')
            mdlWks = get_param('tf4M_CSVposRef_ctrlDesign','ModelWorkspace');
            kp1 = getVariable(mdlWks,'Kp1');
            ki1 = getVariable(mdlWks,'Ki1');
            kp2 = getVariable(mdlWks,'Kp2');
            ki2 = getVariable(mdlWks,'Ki2');
            kp3 = getVariable(mdlWks,'Kp3');
            ki3 = getVariable(mdlWks,'Ki3');
            kp4 = getVariable(mdlWks,'Kp4');
            ki4 = getVariable(mdlWks,'Ki4');

            app.Kp=[kp3 kp4 kp1 kp2];
            app.Ki=[ki3 ki4 ki1 ki2];
            app.Kd=[0 0 0 0];                       
            app.KpEditField_1.Value=app.Kp(1);
            app.KpEditField_2.Value=app.Kp(2);
            app.KpEditField_3.Value=app.Kp(3);
            app.KpEditField_4.Value=app.Kp(4);
            app.KiEditField_1.Value=app.Ki(1);
            app.KiEditField_2.Value=app.Ki(2);
            app.KiEditField_3.Value=app.Ki(3);
            app.KiEditField_4.Value=app.Ki(4);
            app.KdEditField_1.Value=app.Kd(1);
            app.KdEditField_2.Value=app.Kd(2);
            app.KdEditField_3.Value=app.Kd(3);
            app.KdEditField_4.Value=app.Kd(4);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.TimerNTN);
            for i_close=1:4
                app.Motor(i_close).delete;
            end
            delete(app.TimerNTN);  
            delete(app);
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            ModeSelection=app.ModeButtonGroup.SelectedObject.Text;
            if strcmpi(ModeSelection,'Mode 1')
                coef_mode=0.7;
            elseif strcmpi(ModeSelection,'Mode 2')
                coef_mode=0.8;
            else
                coef_mode=1;
            end
            %%%%%%%%%%%%%%%%%%%%%%%
            LevelSelection=app.SpeedButtonGroup.SelectedObject.Text;
            if strcmpi(LevelSelection,'Fast')
                app.TSample=0.18;
            elseif strcmpi(LevelSelection,'Medium')
                app.TSample=0.2;
            else
                app.TSample=0.25;
            end
            app.TimerNTN.Period=app.TSample;
            %%%%%%%%%%%%%%%%%%%%%%%%
            Selected=[ModeSelection ' ' LevelSelection];
            app.SelectedMode.Value=Selected;
            %Cycle
            NumCycle=app.StepEditField.Value;
            % Load ref data
            data=csvread('datanew.csv');
            Theta1LRef=-data(1:30,3);
            Theta2LRef=-data(1:30,4);
            Theta1RRef=data(1:30,1);
            Theta2RRef=data(1:30,2);
            for i=1:NumCycle
                Theta1LRef=[Theta1LRef; -data(31:130,3)];
                Theta2LRef=[Theta2LRef; -data(31:130,4)];
                Theta1RRef=[Theta1RRef; data(31:130,1)];
                Theta2RRef=[Theta2RRef; data(31:130,2)];
            end
            Theta1LRef=[Theta1LRef; -data(231:260,3)];
            Theta2LRef=[Theta2LRef; -data(231:260,4)];
            Theta1RRef=[Theta1RRef; data(231:260,1)];
            Theta2RRef=[Theta2RRef; data(231:260,2)];
            app.N=length(Theta1LRef);
            %
            app.tValue=single(0:app.TSample:((app.N-1)*app.TSample));
            app.ThetaRef=single([Theta1LRef Theta2LRef Theta1RRef Theta2RRef]*coef_mode);
            % Set up Value 0
            app.Error=single(zeros(app.N,4));
            app.ThetaValue=single(zeros(app.N,4)); 
            app.CurrentValue=single(zeros(app.N,4));
            for i_clear=1:4
                plot(app.UIAxes(i_clear),app.tValue,app.ThetaRef(:,i_clear),'b--','LineWidth',1);
                hold(app.UIAxes(i_clear),'on');
            end
        end

        % Button pushed function: SetPIDButton
        function SetPIDButtonPushed(app, event)
            Kp1=app.KpEditField_1.Value;Kp2=app.KpEditField_2.Value;Kp3=app.KpEditField_3.Value;Kp4=app.KpEditField_4.Value;
            app.Kp=[Kp1 Kp2 Kp3 Kp4];
            
            Ki1=app.KiEditField_1.Value;Ki2=app.KiEditField_2.Value;Ki3=app.KiEditField_3.Value;Ki4=app.KiEditField_4.Value;
            app.Ki=[Ki1 Ki2 Ki3 Ki4];
            
            Kd1=app.KdEditField_1.Value;Kd2=app.KdEditField_2.Value;Kd3=app.KdEditField_3.Value;Kd4=app.KdEditField_4.Value;
            app.Kd=[Kd1 Kd2 Kd3 Kd4];
            K=[app.Kp; app.Ki; app.Kd];
            csvwrite('PIDCoeff.csv',K); 
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            for i_clear=1:4
                hold(app.UIAxes(i_clear),'off');
                app.UIAxes(i_clear).NextPlot;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.SelectButtonPushed;
            app.SetPIDButtonPushed;
            %app.ClearPlotButtonPushed;
            app.GoHomeButtonPushed;
            app.i=1;
            %
            for i_run=1:4
                app.Motor(i_run).SetOperationMode(OperationModes.ProfileVelocityMode);
                app.Motor(i_run).EnableNode;
            end
            start(app.TimerNTN);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
                stop(app.TimerNTN);
                for i_stop=1:4
                    app.Motor(i_stop).DisableNode;
                end
                if app.i > app.N
                    app.i=1;
                    app.ClearPlotButtonPushed
                end
                app.ErrorTheta1.Value=double(mean(abs(app.Error(:,1))));
                app.ErrorTheta2.Value=double(mean(abs(app.Error(:,2))));
                app.ErrorTheta3.Value=double(mean(abs(app.Error(:,3))));
                app.ErrorTheta4.Value=double(mean(abs(app.Error(:,4))));
                %
                app.MaxC1.Value=double(max(app.CurrentValue(:,1)));app.MaxC2.Value=double(max(app.CurrentValue(:,2)));
                app.MaxC3.Value=double(max(app.CurrentValue(:,3)));app.MaxC4.Value=double(max(app.CurrentValue(:,4)));
                app.MinC1.Value=double(min(app.CurrentValue(:,1)));app.MinC2.Value=double(min(app.CurrentValue(:,2)));
                app.MinC3.Value=double(min(app.CurrentValue(:,3)));app.MinC4.Value=double(min(app.CurrentValue(:,4)));
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TotalTimeEditField.Value=0;

        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            for i_GoHome=1:4
                app.Motor(i_GoHome).SetOperationMode(OperationModes.ProfilePositionMode); 
                app.Motor(i_GoHome).EnableNode;
                app.Motor(i_GoHome).MotionInPosition(app.qcTheta0(i_GoHome),app.MaxVelocity/2,app.MaxAcceleration/3,1);
                app.Motor(i_GoHome).WaitUntilDone(2000);
                app.Motor(i_GoHome).DisableNode;
                app.Motor(i_GoHome).SetOperationMode(OperationModes.ProfileVelocityMode)
            end
            app.i=1;
        end

        % Button pushed function: SetHomeButton
        function SetHomeButtonPushed(app, event)
            for i_home=1:4
                app.qcTheta0(i_home)=app.Motor(i_home).ActualPosition;
            end
            K=[app.qcTheta0(1) app.qcTheta0(2) app.qcTheta0(3) app.qcTheta0(4)];
            csvwrite('Home.csv',K);
            app.Theta1EditField_Home.Value=app.qcTheta0(1)/app.Coef_Motor;
            app.Theta2EditField_Home.Value=app.qcTheta0(2)/app.Coef_Motor;
            app.Theta3EditField_Home.Value=app.qcTheta0(3)/app.Coef_Motor;
            app.Theta4EditField_Home.Value=app.qcTheta0(4)/app.Coef_Motor;
        end

        % Button pushed function: HipLeftButton
        function HipLeftButtonPushed(app, event)
            app.Motor(1).SetOperationMode(OperationModes.ProfilePositionMode); 
            app.Motor(1).EnableNode;
            app.Motor(1).MotionInPosition(4096,app.MaxVelocity/2,app.MaxAcceleration/2,0);
            app.Motor(1).WaitUntilDone(5000);
            app.Motor(1).MotionInPosition(-4096,app.MaxVelocity/2,app.MaxAcceleration/2,0);
            app.Motor(1).WaitUntilDone(5000);
            app.Motor(1).DisableNode;
            app.Motor(1).SetOperationMode(OperationModes.ProfileVelocityMode)
        end

        % Button pushed function: KneeLeftButton
        function KneeLeftButtonPushed(app, event)
            app.Motor(2).SetOperationMode(OperationModes.ProfilePositionMode); 
            app.Motor(2).EnableNode;
            app.Motor(2).MotionInPosition(4096,app.MaxVelocity/2,app.MaxAcceleration/2,0);
            app.Motor(2).WaitUntilDone(5000);
            app.Motor(2).MotionInPosition(-4096,app.MaxVelocity/2,app.MaxAcceleration/2,0);
            app.Motor(2).WaitUntilDone(5000);
            app.Motor(2).DisableNode;
            app.Motor(2).SetOperationMode(OperationModes.ProfileVelocityMode)
        end

        % Button pushed function: HipRightButton
        function HipRightButtonPushed(app, event)
            app.Motor(3).SetOperationMode(OperationModes.ProfilePositionMode); 
            app.Motor(3).EnableNode;
            app.Motor(3).MotionInPosition(4096,app.MaxVelocity/2,app.MaxAcceleration/2,0);
            app.Motor(3).WaitUntilDone(5000);
            app.Motor(3).MotionInPosition(-4096,app.MaxVelocity/2,app.MaxAcceleration/2,0);
            app.Motor(3).WaitUntilDone(5000);
            app.Motor(3).DisableNode;
            app.Motor(3).SetOperationMode(OperationModes.ProfileVelocityMode)
        end

        % Button pushed function: KneeRightButton
        function KneeRightButtonPushed(app, event)
            app.Motor(4).SetOperationMode(OperationModes.ProfilePositionMode); 
            app.Motor(4).EnableNode;
            app.Motor(4).MotionInPosition(4096,app.MaxVelocity/2,app.MaxAcceleration/2,0);
            app.Motor(4).WaitUntilDone(5000);
            app.Motor(4).MotionInPosition(-4096,app.MaxVelocity/2,app.MaxAcceleration/2,0);
            app.Motor(4).WaitUntilDone(5000);
            app.Motor(4).DisableNode;
            app.Motor(4).SetOperationMode(OperationModes.ProfileVelocityMode) 
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            for i_run=1:4
                app.Motor(i_run).SetOperationMode(OperationModes.ProfileVelocityMode);
                app.Motor(i_run).EnableNode;
            end
            for i=1:10
                app.NguyenThanhNguyen;
            end
            app.StopButtonPushed;
        end

        % Button pushed function: AddHLButton
        function AddHLButtonPushed(app, event)
            app.Motor(1).SetOperationMode(OperationModes.ProfilePositionMode); 
            app.Motor(1).EnableNode;
            app.Motor(1).MotionInPosition(-4096,app.MaxVelocity/2,app.MaxAcceleration/3,0);
        end

        % Button pushed function: SubHLButton
        function SubHLButtonPushed(app, event)
            app.Motor(1).SetOperationMode(OperationModes.ProfilePositionMode);
            app.Motor(1).EnableNode;
            app.Motor(1).MotionInPosition(4096,app.MaxVelocity/2,app.MaxAcceleration/3,0);
        end

        % Button pushed function: AddKLButton
        function AddKLButtonPushed(app, event)
            app.Motor(2).SetOperationMode(OperationModes.ProfilePositionMode); 
            app.Motor(2).EnableNode;
            app.Motor(2).MotionInPosition(4096,app.MaxVelocity/2,app.MaxAcceleration/3,0);            
        end

        % Button pushed function: SubKLButton
        function SubKLButtonPushed(app, event)
            app.Motor(2).SetOperationMode(OperationModes.ProfilePositionMode);
            app.Motor(2).EnableNode;
            app.Motor(2).MotionInPosition(-4096,app.MaxVelocity/2,app.MaxAcceleration/3,0);            
        end

        % Button pushed function: AddHRButton
        function AddHRButtonPushed(app, event)
            app.Motor(3).SetOperationMode(OperationModes.ProfilePositionMode); 
            app.Motor(3).EnableNode;
            app.Motor(3).MotionInPosition(4096,app.MaxVelocity/2,app.MaxAcceleration/3,0);            
        end

        % Button pushed function: SubHRButton
        function SubHRButtonPushed(app, event)
            app.Motor(3).SetOperationMode(OperationModes.ProfilePositionMode);
            app.Motor(3).EnableNode;
            app.Motor(3).MotionInPosition(-4096,app.MaxVelocity/2,app.MaxAcceleration/3,0);            
        end

        % Button pushed function: AddKRButton
        function AddKRButtonPushed(app, event)
            app.Motor(4).SetOperationMode(OperationModes.ProfilePositionMode); 
            app.Motor(4).EnableNode;
            app.Motor(4).MotionInPosition(-4096,app.MaxVelocity/2,app.MaxAcceleration/3,0);            
        end

        % Button pushed function: SubKRButton
        function SubKRButtonPushed(app, event)
            app.Motor(4).SetOperationMode(OperationModes.ProfilePositionMode);
            app.Motor(4).EnableNode;
            app.Motor(4).MotionInPosition(4096,app.MaxVelocity/2,app.MaxAcceleration/3,0);            
        end

        % Button pushed function: ResetMotorButton
        function ResetMotorButtonPushed(app, event)
            app.Hip_Left_Motor.ClearErrorState;
            app.Knee_Left_Motor.ClearErrorState;
            app.Hip_Right_Motor.ClearErrorState;
            app.Knee_Right_Motor.ClearErrorState;
        end

        % Button pushed function: GetPositionButton
        function GetPositionButtonPushed(app, event)
            app.Theta1EditField.Value=app.Motor(1).ActualPosition/app.Coef_Motor;
            app.Theta2EditField.Value=app.Motor(2).ActualPosition/app.Coef_Motor;
            app.Theta3EditField.Value=app.Motor(3).ActualPosition/app.Coef_Motor;
            app.Theta4EditField.Value=app.Motor(4).ActualPosition/app.Coef_Motor;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExoskeletonLabel = uilabel(app.UIFigure);
            app.ExoskeletonLabel.Text = 'Exoskeleton';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UserTab = uitab(app.TabGroup);
            app.UserTab.Title = 'User';
            app.UserinformationPanel = uipanel(app.UserTab);
            app.UserinformationPanel.Title = 'User information';
            app.MassLabel = uilabel(app.UserinformationPanel);
            app.MassLabel.Text = 'Mass';
            app.AgeLabel = uilabel(app.UserinformationPanel);
            app.AgeLabel.Text = 'Age';
            app.SexLabel = uilabel(app.UserinformationPanel);
            app.SexLabel.Text = 'Sex';
            app.NameLabel = uilabel(app.UserinformationPanel);
            app.NameLabel.Text = 'Name';
            app.Mass = uieditfield(app.UserinformationPanel, 'numeric');
            app.kgLabel = uilabel(app.UserinformationPanel);
            app.kgLabel.Text = 'kg';
            app.EditField_2 = uieditfield(app.UserinformationPanel, 'numeric');
            app.DropDown = uidropdown(app.UserinformationPanel);
            app.DropDown.Items = {'Male', 'Female', 'Others'};
            app.DropDown.Value = 'Male';
            app.EditField_3 = uieditfield(app.UserinformationPanel, 'text');
            app.PraticeInfomationPanel = uipanel(app.UserTab);
            app.PraticeInfomationPanel.Title = 'Pratice Infomation';
            app.TotalTimeEditFieldLabel = uilabel(app.PraticeInfomationPanel);
            app.TotalTimeEditFieldLabel.Text = 'Total Time';
            app.TotalTimeEditField = uieditfield(app.PraticeInfomationPanel, 'numeric');
            app.TotalTimeEditField.Editable = 'off';
            app.TotalstepEditFieldLabel = uilabel(app.PraticeInfomationPanel);
            app.TotalstepEditFieldLabel.Text = 'Total step';
            app.TotalstepEditField = uieditfield(app.PraticeInfomationPanel, 'numeric');
            app.ResetButton = uibutton(app.PraticeInfomationPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MovingModePanel = uipanel(app.UserTab);
            app.MovingModePanel.Title = 'Moving Mode';
            app.ModeButtonGroup = uibuttongroup(app.MovingModePanel);
            app.ModeButtonGroup.Title = 'Mode';
            app.Mode1Button = uiradiobutton(app.ModeButtonGroup);
            app.Mode1Button.Text = 'Mode 1';
            app.Mode1Button.Value = true;
            app.Mode2Button = uiradiobutton(app.ModeButtonGroup);
            app.Mode2Button.Text = 'Mode 2';
            app.Mode3Button = uiradiobutton(app.ModeButtonGroup);
            app.Mode3Button.Text = 'Mode 3';
            app.SpeedButtonGroup = uibuttongroup(app.MovingModePanel);
            app.SpeedButtonGroup.Title = 'Speed';
            app.LowButton = uiradiobutton(app.SpeedButtonGroup);
            app.LowButton.Text = 'Low';
            app.LowButton.Value = true;
            app.MediumButton = uiradiobutton(app.SpeedButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.FastButton = uiradiobutton(app.SpeedButtonGroup);
            app.FastButton.Text = 'Fast';
            app.SelectButton = uibutton(app.MovingModePanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectedMode = uieditfield(app.MovingModePanel, 'text');
            app.SelectedMode.Editable = 'off';
            app.StepEditFieldLabel = uilabel(app.MovingModePanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.MovingModePanel, 'numeric');
            app.StepEditField.Limits = [0 Inf];
            app.StepEditField.Value = 1;
            app.ContinuousMoveButton = uibutton(app.MovingModePanel, 'state');
            app.ContinuousMoveButton.Text = 'Continuous Move';
            app.CurrentPanel = uipanel(app.UserTab);
            app.CurrentPanel.Title = 'Current';
            app.LampDC4 = uilamp(app.CurrentPanel);
            app.LampDC3 = uilamp(app.CurrentPanel);
            app.LampDC2 = uilamp(app.CurrentPanel);
            app.LampDC1 = uilamp(app.CurrentPanel);
            app.LeftUpLabel = uilabel(app.CurrentPanel);
            app.LeftUpLabel.Text = 'Left Up';
            app.CurrentGauge1 = uigauge(app.CurrentPanel, 'linear');
            app.CurrentGauge1.Limits = [-8 8];
            app.LeftDownLabel = uilabel(app.CurrentPanel);
            app.LeftDownLabel.Text = 'Left Down';
            app.CurrentGauge2 = uigauge(app.CurrentPanel, 'linear');
            app.CurrentGauge2.Limits = [-8 8];
            app.RightUpLabel = uilabel(app.CurrentPanel);
            app.RightUpLabel.Text = 'Right Up';
            app.CurrentGauge3 = uigauge(app.CurrentPanel, 'linear');
            app.CurrentGauge3.Limits = [-8 8];
            app.RightDownLabel = uilabel(app.CurrentPanel);
            app.RightDownLabel.Text = 'Right Down';
            app.CurrentGauge4 = uigauge(app.CurrentPanel, 'linear');
            app.CurrentGauge4.Limits = [-8 8];
            app.MaxC3 = uieditfield(app.CurrentPanel, 'numeric');
            app.MinC3 = uieditfield(app.CurrentPanel, 'numeric');
            app.MaxC4 = uieditfield(app.CurrentPanel, 'numeric');
            app.MinC4 = uieditfield(app.CurrentPanel, 'numeric');
            app.MaxC1 = uieditfield(app.CurrentPanel, 'numeric');
            app.MinC1 = uieditfield(app.CurrentPanel, 'numeric');
            app.MaxC2 = uieditfield(app.CurrentPanel, 'numeric');
            app.MinC2 = uieditfield(app.CurrentPanel, 'numeric');
            app.LeftGaugeLabel = uilabel(app.UserTab);
            app.LeftGaugeLabel.Text = 'Left';
            app.LeftGauge = uigauge(app.UserTab, 'linear');
            app.RightGaugeLabel = uilabel(app.UserTab);
            app.RightGaugeLabel.Text = 'Right';
            app.RightGauge = uigauge(app.UserTab, 'linear');
            app.LampLeft = uilamp(app.UserTab);
            app.LampRight = uilamp(app.UserTab);
            app.TechnicianTab = uitab(app.TabGroup);
            app.TechnicianTab.Title = 'Technician';
            app.UIAxes3 = uiaxes(app.TechnicianTab);
            ylabel(app.UIAxes3, 'Degree')
            app.PIDConfigurationPanel = uipanel(app.TechnicianTab);
            app.PIDConfigurationPanel.Title = 'PID Configuration';
            app.KpEditField_2 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KiEditField_2 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KdEditField_2 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.HipLeftLabel = uilabel(app.PIDConfigurationPanel);
            app.HipLeftLabel.Text = 'Hip Left';
            app.KneeLeftLabel = uilabel(app.PIDConfigurationPanel);
            app.KneeLeftLabel.Text = 'Knee Left';
            app.KpEditField_2Label = uilabel(app.PIDConfigurationPanel);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField_1 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KiEditField_2Label = uilabel(app.PIDConfigurationPanel);
            app.KiEditField_2Label.Text = 'Ki';
            app.KiEditField_1 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KdEditField_2Label = uilabel(app.PIDConfigurationPanel);
            app.KdEditField_2Label.Text = 'Kd';
            app.KdEditField_1 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KpEditField_4 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KiEditField_4 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KdEditField_4 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.HipRightLabel = uilabel(app.PIDConfigurationPanel);
            app.HipRightLabel.Text = 'Hip Right';
            app.KneeRightLabel = uilabel(app.PIDConfigurationPanel);
            app.KneeRightLabel.Text = 'Knee Right';
            app.KpEditField_3 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KiEditField_3 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.KdEditField_3 = uieditfield(app.PIDConfigurationPanel, 'numeric');
            app.SetPIDButton = uibutton(app.PIDConfigurationPanel, 'push');
            app.SetPIDButton.ButtonPushedFcn = createCallbackFcn(app, @SetPIDButtonPushed, true);
            app.SetPIDButton.Text = 'Set';
            app.GoHomeButton = uibutton(app.TechnicianTab, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'Go Home';
            app.UIAxes1 = uiaxes(app.TechnicianTab);
            ylabel(app.UIAxes1, 'Degree')
            app.UIAxes4 = uiaxes(app.TechnicianTab);
            ylabel(app.UIAxes4, 'Degree')
            app.UIAxes2 = uiaxes(app.TechnicianTab);
            ylabel(app.UIAxes2, 'Degree')
            app.ClearPlotButton = uibutton(app.TechnicianTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ErrorTheta1Label = uilabel(app.TechnicianTab);
            app.ErrorTheta1Label.Text = 'Error Theta 1';
            app.ErrorTheta1 = uieditfield(app.TechnicianTab, 'numeric');
            app.ErrorTheta1.Editable = 'off';
            app.ErrorTheta2Label = uilabel(app.TechnicianTab);
            app.ErrorTheta2Label.Text = 'Error Theta 2';
            app.ErrorTheta2 = uieditfield(app.TechnicianTab, 'numeric');
            app.ErrorTheta2.Editable = 'off';
            app.ErrorTheta3Label = uilabel(app.TechnicianTab);
            app.ErrorTheta3Label.Text = 'Error Theta 3';
            app.ErrorTheta3 = uieditfield(app.TechnicianTab, 'numeric');
            app.ErrorTheta3.Editable = 'off';
            app.ErrorTheta4Label = uilabel(app.TechnicianTab);
            app.ErrorTheta4Label.Text = 'Error Theta 4';
            app.ErrorTheta4 = uieditfield(app.TechnicianTab, 'numeric');
            app.ErrorTheta4.Editable = 'off';
            app.DebugButton = uibutton(app.TechnicianTab, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug Button';
            app.ResetMotorButton = uibutton(app.TechnicianTab, 'push');
            app.ResetMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMotorButtonPushed, true);
            app.ResetMotorButton.Text = 'Reset Motor';
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.ActualPositionPanel = uipanel(app.HomeTab);
            app.ActualPositionPanel.Title = 'Actual Position';
            app.Theta3EditFieldLabel = uilabel(app.ActualPositionPanel);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.ActualPositionPanel, 'numeric');
            app.Theta3EditField.Editable = 'off';
            app.Theta1EditFieldLabel = uilabel(app.ActualPositionPanel);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.ActualPositionPanel, 'numeric');
            app.Theta1EditField.Editable = 'off';
            app.Theta4EditFieldLabel = uilabel(app.ActualPositionPanel);
            app.Theta4EditFieldLabel.Text = 'Theta 4';
            app.Theta4EditField = uieditfield(app.ActualPositionPanel, 'numeric');
            app.Theta4EditField.Editable = 'off';
            app.Theta2EditFieldLabel = uilabel(app.ActualPositionPanel);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.ActualPositionPanel, 'numeric');
            app.Theta2EditField.Editable = 'off';
            app.HomePositionPanel = uipanel(app.HomeTab);
            app.HomePositionPanel.Title = 'Home Position';
            app.Theta3EditField_2Label = uilabel(app.HomePositionPanel);
            app.Theta3EditField_2Label.Text = 'Theta 3';
            app.Theta3EditField_Home = uieditfield(app.HomePositionPanel, 'numeric');
            app.Theta3EditField_Home.Editable = 'off';
            app.Theta1EditField_2Label = uilabel(app.HomePositionPanel);
            app.Theta1EditField_2Label.Text = 'Theta 1';
            app.Theta1EditField_Home = uieditfield(app.HomePositionPanel, 'numeric');
            app.Theta1EditField_Home.Editable = 'off';
            app.Theta4EditField_2Label = uilabel(app.HomePositionPanel);
            app.Theta4EditField_2Label.Text = 'Theta 4';
            app.Theta4EditField_Home = uieditfield(app.HomePositionPanel, 'numeric');
            app.Theta4EditField_Home.Editable = 'off';
            app.Theta2EditField_2Label = uilabel(app.HomePositionPanel);
            app.Theta2EditField_2Label.Text = 'Theta 2';
            app.Theta2EditField_Home = uieditfield(app.HomePositionPanel, 'numeric');
            app.Theta2EditField_Home.Editable = 'off';
            app.SetHomeButton = uibutton(app.HomeTab, 'push');
            app.SetHomeButton.ButtonPushedFcn = createCallbackFcn(app, @SetHomeButtonPushed, true);
            app.SetHomeButton.Text = 'Set Home';
            app.TestRespondPanel = uipanel(app.HomeTab);
            app.TestRespondPanel.Title = 'Test Respond';
            app.HipRightButton = uibutton(app.TestRespondPanel, 'push');
            app.HipRightButton.ButtonPushedFcn = createCallbackFcn(app, @HipRightButtonPushed, true);
            app.HipRightButton.Text = 'Hip Right';
            app.HipLeftButton = uibutton(app.TestRespondPanel, 'push');
            app.HipLeftButton.ButtonPushedFcn = createCallbackFcn(app, @HipLeftButtonPushed, true);
            app.HipLeftButton.Text = 'Hip Left';
            app.KneeLeftButton = uibutton(app.TestRespondPanel, 'push');
            app.KneeLeftButton.ButtonPushedFcn = createCallbackFcn(app, @KneeLeftButtonPushed, true);
            app.KneeLeftButton.Text = 'Knee Left';
            app.KneeRightButton = uibutton(app.TestRespondPanel, 'push');
            app.KneeRightButton.ButtonPushedFcn = createCallbackFcn(app, @KneeRightButtonPushed, true);
            app.KneeRightButton.Text = 'Knee Right';
            app.HandlesPanel = uipanel(app.HomeTab);
            app.HandlesPanel.Title = 'Handles';
            app.SelecthandlesstepDropDownLabel = uilabel(app.HandlesPanel);
            app.SelecthandlesstepDropDownLabel.Text = 'Select handles step';
            app.SelecthandlesstepDropDown = uidropdown(app.HandlesPanel);
            app.SelecthandlesstepDropDown.Items = {'Slow', 'Normal', 'Fast'};
            app.SelecthandlesstepDropDown.Value = 'Normal';
            app.Panel = uipanel(app.HandlesPanel);
            app.AddHLButton = uibutton(app.Panel, 'push');
            app.AddHLButton.ButtonPushedFcn = createCallbackFcn(app, @AddHLButtonPushed, true);
            app.AddHLButton.Text = 'Up';
            app.SubHLButton = uibutton(app.Panel, 'push');
            app.SubHLButton.ButtonPushedFcn = createCallbackFcn(app, @SubHLButtonPushed, true);
            app.SubHLButton.Text = 'Down';
            app.HipLeftLabel_2 = uilabel(app.Panel);
            app.HipLeftLabel_2.Text = 'Hip Left';
            app.Panel_2 = uipanel(app.HandlesPanel);
            app.AddKLButton = uibutton(app.Panel_2, 'push');
            app.AddKLButton.ButtonPushedFcn = createCallbackFcn(app, @AddKLButtonPushed, true);
            app.AddKLButton.Text = 'Up';
            app.SubKLButton = uibutton(app.Panel_2, 'push');
            app.SubKLButton.ButtonPushedFcn = createCallbackFcn(app, @SubKLButtonPushed, true);
            app.SubKLButton.Text = 'Down';
            app.KneeLeftLabel_2 = uilabel(app.Panel_2);
            app.KneeLeftLabel_2.Text = 'Knee Left';
            app.Panel_3 = uipanel(app.HandlesPanel);
            app.AddHRButton = uibutton(app.Panel_3, 'push');
            app.AddHRButton.ButtonPushedFcn = createCallbackFcn(app, @AddHRButtonPushed, true);
            app.AddHRButton.Text = 'Up';
            app.SubHRButton = uibutton(app.Panel_3, 'push');
            app.SubHRButton.ButtonPushedFcn = createCallbackFcn(app, @SubHRButtonPushed, true);
            app.SubHRButton.Text = 'Down';
            app.HipRightLabel_2 = uilabel(app.Panel_3);
            app.HipRightLabel_2.Text = 'Hip Right';
            app.Panel_4 = uipanel(app.HandlesPanel);
            app.AddKRButton = uibutton(app.Panel_4, 'push');
            app.AddKRButton.ButtonPushedFcn = createCallbackFcn(app, @AddKRButtonPushed, true);
            app.AddKRButton.Text = 'Up';
            app.SubKRButton = uibutton(app.Panel_4, 'push');
            app.SubKRButton.ButtonPushedFcn = createCallbackFcn(app, @SubKRButtonPushed, true);
            app.SubKRButton.Text = 'Down';
            app.KneeRightLabel_2 = uilabel(app.Panel_4);
            app.KneeRightLabel_2.Text = 'Knee Right';
            app.GetPositionButton = uibutton(app.HomeTab, 'push');
            app.GetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GetPositionButtonPushed, true);
            app.GetPositionButton.Text = 'Get Position';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exoskeleton_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end